classdef gui3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup2                   matlab.ui.container.TabGroup
        Tab3                        matlab.ui.container.Tab
        Label12                     matlab.ui.control.Label
        Panel3                      matlab.ui.container.Panel
        LigarSwitchLabel            matlab.ui.control.Label
        LigarSwitch                 matlab.ui.control.RockerSwitch
        LabelGauge                  matlab.ui.control.Label
        GaugeUmidade                matlab.ui.control.Gauge
        temp                        matlab.ui.control.NumericEditField
        TempCLabel                  matlab.ui.control.Label
        EnsaiosLabel                matlab.ui.control.Label
        medidas                     matlab.ui.control.NumericEditField
        timoEnsaioLabel             matlab.ui.control.Label
        horaField                   matlab.ui.control.EditField
        Panel                       matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        mOnOff                      matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        LabelLamp1                  matlab.ui.control.Label
        Lamp1                       matlab.ui.control.Lamp
        LabelLamp2                  matlab.ui.control.Label
        Lamp2                       matlab.ui.control.Lamp
        LabelLamp3                  matlab.ui.control.Label
        Lamp3                       matlab.ui.control.Lamp
        LabelLamp4                  matlab.ui.control.Label
        Lamp4                       matlab.ui.control.Lamp
        LabelLamp5                  matlab.ui.control.Label
        Lamp5                       matlab.ui.control.Lamp
        LabelLamp6                  matlab.ui.control.Label
        Lamp6                       matlab.ui.control.Lamp
        LabelLamp7                  matlab.ui.control.Label
        Lamp7                       matlab.ui.control.Lamp
        LabelLamp8                  matlab.ui.control.Label
        Lamp8                       matlab.ui.control.Lamp
        LabelLamp9                  matlab.ui.control.Label
        Lamp9                       matlab.ui.control.Lamp
        LabelLamp10                 matlab.ui.control.Label
        Lamp10                      matlab.ui.control.Lamp
        LabelLamp11                 matlab.ui.control.Label
        Lamp11                      matlab.ui.control.Lamp
        LabelLamp12                 matlab.ui.control.Label
        Lamp12                      matlab.ui.control.Lamp
        Tab2                        matlab.ui.container.Tab
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Reator1M                    matlab.ui.control.RadioButton
        Reator2M                    matlab.ui.control.RadioButton
        Reator3M                    matlab.ui.control.RadioButton
        Reator4M                    matlab.ui.control.RadioButton
        Reator5M                    matlab.ui.control.RadioButton
        Reator6M                    matlab.ui.control.RadioButton
        Reator7M                    matlab.ui.control.RadioButton
        Reator8M                    matlab.ui.control.RadioButton
        Reator9M                    matlab.ui.control.RadioButton
        Reator10M                   matlab.ui.control.RadioButton
        Reator11M                   matlab.ui.control.RadioButton
        Reator12M                   matlab.ui.control.RadioButton
        Panel2                      matlab.ui.container.Panel
        LabelTextArea               matlab.ui.control.Label
        Status                      matlab.ui.control.TextArea
        Tab4                        matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        Bplot                       matlab.ui.control.Button
        Label13                     matlab.ui.control.Label
        NumericEditField2           matlab.ui.control.NumericEditField
        LabelNumericEditField       matlab.ui.control.Label
        NumericEditField1           matlab.ui.control.NumericEditField
        Tab5                        matlab.ui.container.Tab
        Button3                     matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        PontoInicialEditFieldLabel  matlab.ui.control.Label
        PontoInicialEditField       matlab.ui.control.NumericEditField
        PontoFinalEditFieldLabel    matlab.ui.control.Label
        PontoFinalEditField         matlab.ui.control.NumericEditField
        EditField4Label             matlab.ui.control.Label
        ExportField                 matlab.ui.control.EditField
        bExport                     matlab.ui.control.Button
        Tab6                        matlab.ui.container.Tab
        Tab7                        matlab.ui.container.Tab
        Batualizar1                 matlab.ui.control.Button
        ListBoxLabel                matlab.ui.control.Label
        ListBox                     matlab.ui.control.ListBox
        Bluetooth1EditFieldLabel    matlab.ui.control.Label
        Bluetooth1EditField         matlab.ui.control.EditField
        Bluetooth2EditFieldLabel    matlab.ui.control.Label
        Bluetooth2EditField         matlab.ui.control.EditField
        Button3_2                   matlab.ui.control.Button
        Button3_3                   matlab.ui.control.Button
        WarmupEditFieldLabel        matlab.ui.control.Label
        WarmupEditField             matlab.ui.control.NumericEditField
        Button4                     matlab.ui.control.Button
        Button4_2                   matlab.ui.control.Button
        DelayGasEditFieldLabel      matlab.ui.control.Label
        DelayGasEditField           matlab.ui.control.NumericEditField
        DelayFlushEditFieldLabel    matlab.ui.control.Label
        DelayFlushEditField         matlab.ui.control.NumericEditField
        DelayFlushv1EditFieldLabel  matlab.ui.control.Label
        DelayFlushv1EditField       matlab.ui.control.NumericEditField
    end

    %     arduino = Bluetooth('HC-06', 1);
    %     T6615 = Bluetooth('SAAB', 1);
    properties (Access = public)
        autoUpdate
        r1 % Description
        r2 % Description
        r3
        r4
        r5
        r6
        r7
        r8
        r9
        r10
        r11
        r12
        radiovisible = 0 % Description
        xdata % Description
        ydata
        export
        id
        dias
        delaywarmup
        delaygas
        delayflush
        delayflushv1
        bt1
        bt2
        %         arduino = Bluetooth('HC-06', 1)
        %         T6615 = Bluetooth('SAAB', 1)
        %
    end
    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = public)
        
        function results = rotina(app)
            rotina_gui;
        end    
        
        function results = teste(app)
            pedro = 'oi'
        end
        
        function results = radioBox(app)
            if app.radiovisible == 1
                app.Reator1M.Enable = 'on';
                app.Reator2M.Enable = 'on';
                app.Reator3M.Enable = 'on';
                app.Reator4M.Enable = 'on';
                app.Reator5M.Enable = 'on';
                app.Reator6M.Enable = 'on';
                app.Reator7M.Enable = 'on';
                app.Reator8M.Enable = 'on';
                app.Reator9M.Enable = 'on';
                app.Reator10M.Enable = 'on';
                app.Reator11M.Enable = 'on';
                app.Reator12M.Enable = 'on';
            else
                app.Reator1M.Enable = 'off';
                app.Reator2M.Enable = 'off';
                app.Reator3M.Enable = 'off';
                app.Reator4M.Enable = 'off';
                app.Reator5M.Enable = 'off';
                app.Reator6M.Enable = 'off';
                app.Reator7M.Enable = 'off';
                app.Reator8M.Enable = 'off';
                app.Reator9M.Enable = 'off';
                app.Reator10M.Enable = 'off';
                app.Reator11M.Enable = 'off';
                app.Reator12M.Enable = 'off';
            end
            
            
        end
        
    end %fim dos metodos


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.autoUpdate = 1;
            
            conn = database('Saab Matlab','root','');
            
            sqlquery = sprintf( 'SELECT * FROM `matlab2`.`config` WHERE 1;');
            data = select(conn,sqlquery);
            %app.id = table2array(data(1,1));
            app.dias = table2array(data(1,2));
            bt1 = table2array(data(1,3));
            app.bt1 = cell2mat(bt1);
            bt2 = table2array(data(1,4));
            app.bt2 = cell2mat(bt2);
            app.delaywarmup = table2array(data(1,5));
            app.delaygas = table2array(data(1,6));
            app.delayflush = table2array(data(1,7));
            app.delayflushv1 = table2array(data(1,8));
            
            app.Bluetooth1EditField.Value = app.bt1;
            app.Bluetooth2EditField.Value = app.bt2;
            app.WarmupEditField.Value = app.delaywarmup;
            app.DelayGasEditField.Value = app.delaygas;
            app.DelayFlushEditField.Value = app.delayflush;
            app.DelayFlushv1EditField.Value = app.delayflushv1;
            
            sqlquery = sprintf( 'SELECT * FROM `matlab`.`Reator 12` WHERE ID = (SELECT MAX(id) FROM `matlab`.`Reator 12`);');
            data = select(conn,sqlquery);
            app.id = table2array(data(1,1));
            app.medidas.Value = app.id;
            hora = table2array(data(1,4));
            app.horaField.Value = cell2mat(hora);
            
        end

        % Button pushed function: Batualizar1
        function Batualizar1ButtonPushed(app, event)
            
            if strcmp(app.ListBox.Value,'Criar Tabelas')
                conn = database('Saab Matlab','root','');
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 1` ( `id` INT(3) NOT NULL AUTO_INCREMENT , `ppm0` FLOAT NOT NULL , `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 2` ( `id` INT(3) NOT NULL AUTO_INCREMENT , `ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 3` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 4` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 5` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL, `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 6` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 7` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 8` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 9` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 10` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 11` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`Reator 12` ( `id` INT(3) NOT NULL AUTO_INCREMENT ,`ppm0` FLOAT NOT NULL, `ppm` int(5) NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery =  'CREATE TABLE `matlab2`.`umidade` ( `id` INT(3) NOT NULL AUTO_INCREMENT , `umidade` FLOAT NOT NULL , `hour` timestamp NOT NULL DEFAULT CURRENT_TIMESTAMP , PRIMARY KEY (`id`)) ENGINE = InnoDB;';
                curs = exec(conn,sqlquery);
                
                sqlquery = ['CREATE TABLE `matlab2`.`config` ('...
                    '`id` int(4) NOT NULL AUTO_INCREMENT,'...
                    '`dias` int(4) NOT NULL,'...
                    '`bt1` varchar(10) NOT NULL,'...
                    '`bt2` varchar(10) NOT NULL,'...
                    '`delaywarmup` int(4) NOT NULL,'...
                    '`delaygas` int(4) NOT NULL,'...
                    '`delayflush` int(4) NOT NULL,'...
                    '`delayflushv1` int(4) NOT NULL,'...
                    'PRIMARY KEY (`id`)'...
                    ') ENGINE=InnoDB DEFAULT CHARSET=latin1'];
                curs = exec(conn,sqlquery);
                
                conn = database('Saab Matlab','root','');
                sqlquery=['INSERT INTO `matlab2`.`config`'...
                    '(dias,delaywarmup,delaygas,delayflush,delayflushv1,bt1,bt2)'...
                    'VALUES (1,7,7,7,7,"SAAB","HC-06");'];
                curs = exec(conn,sqlquery);
                
                close(curs);
                close(conn);
            end
            if strcmp(app.ListBox.Value,'Deletar Tabelas')
                
                conn = database('Saab Matlab','root','');
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 1`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 2`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 3`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 4`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 5`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 6`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 7`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 8`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 9`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 10`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 11`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`reator 12`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`umidade`;');
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'DROP TABLE `matlab2`.`config`;');
                curs = exec(conn,sqlquery);
                
                close(curs);
                close(conn);
            end
            
        end

        % Button pushed function: Button3
        function Button3ButtonPushed(app, event)
            app.export=0;
            gui_dados;
            %app.UITable.Position = [160 30 320 150];
            
        end

        % Button pushed function: Button3_2
        function Button3_2Pushed(app, event)
            
            app.delaywarmup = app.WarmupEditField.Value
            app.delaygas = app.DelayGasEditField.Value
            app.delayflush = app.DelayFlushEditField.Value
            app.delayflushv1 = app.DelayFlushv1EditField.Value
            app.bt1 = app.Bluetooth1EditField.Value
            app.bt2 = app.Bluetooth2EditField.Value
            
            conn = database('Saab Matlab','root','');
            
            sqlquery = sprintf('UPDATE `matlab2`.`config` SET delaywarmup=%d,delaygas=%d,delayflush=%d,delayflushv1=%d,bt1="%s",bt2="%s" WHERE 1;',app.delaywarmup,app.delaygas,app.delayflush,app.delayflushv1,app.bt1,app.bt2);
            app.delaywarmup
            %sqlquery2 = sprintf('UPDATE `matlab2`.`config` SET delaywarmup=%d WHERE 1;',app.delaywarmup);
            curs = exec(conn,sqlquery);
            close(conn);
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            %app.autoUpdate = 1;
            if(app.autoUpdate)
                if app.Reator1M.Value == true
                    app.Status.Value='Reator 1 Selecionado.';
                    app.r1=1;
                end
                if app.Reator2M.Value == true
                    app.Status.Value='Reator 2 Selecionado.';
                    app.r2=1;
                end
                if app.Reator3M.Value == true
                    app.Status.Value='Reator 3 Selecionado.';
                    app.r3=1;
                end
                if app.Reator4M.Value == true
                    app.Status.Value='Reator 4 Selecionado.';
                    app.r4=1;
                end
                if app.Reator5M.Value == true
                    app.Status.Value='Reator 5 Selecionado.';
                    app.r5=1;
                end
                if app.Reator6M.Value == true
                    app.Status.Value='Reator 6 Selecionado.';
                    app.r6=1;
                end
                if app.Reator7M.Value == true
                    app.Status.Value='Reator 7 Selecionado.';
                    app.r7=1;
                end
                if app.Reator8M.Value == true
                    app.Status.Value='Reator 8 Selecionado.';
                    app.r8=1;
                end
                if app.Reator9M.Value == true
                    app.Status.Value='Reator 9 Selecionado.';
                    app.r9=1;
                end
                if app.Reator10M.Value == true
                    app.Status.Value='Reator 10 Selecionado.';
                    app.r10=1;
                end
                if app.Reator11M.Value == true
                    app.Status.Value='Reator 11 Selecionado.';
                    app.r11=1;
                end
                if app.Reator12M.Value == true
                    app.Status.Value='Reator 12 Selecionado.';
                    app.r12=1;
                end
                
            end
            
        end

        % Value changed function: LigarSwitch
        function LigarSwitchValueChanged(app, event)
            value = app.LigarSwitch.Value;
            if strcmp(value,'On')
                %app.Button2.BackgroundColor = [0.128 0.255 0];
                rotina_gui;
            else
                %app.Button2.BackgroundColor = [0.128 0.255 0];
                app.Lamp1.Enable = 'off';
                app.LabelLamp1.Enable = 'off';
                app.Lamp1.Color = [1 0 0];
            end
        end

        % Button pushed function: bExport
        function bExportButtonPushed(app, event)
            app.export = 1;
            gui_dados;
        end

        % Button pushed function: Bplot
        function bPloclick(app, event)
            n1= app.NumericEditField1.Value;
            n2= app.NumericEditField2.Value;
            if n1>0 && n2>0 && n1<n2
                conn = database('Saab Matlab','root','');
                i=0;
                sqlquery = sprintf('SELECT id FROM `matlab`.`Reator 12` WHERE id >= %d AND id <=%d ;',n1,n2);
                x = exec(conn,sqlquery);
                x = fetch(x);
                x = cell2mat(x.Data);
                while i<12
                    
                    i=i+1; %contador
                    iS = int2str(i); %converte o contador em uma strin
                    sqlquery = sprintf('SELECT ppm FROM `matlab`.`Reator %s` WHERE id >= %d AND id <= %d;',iS,n1,n2);
                    y = exec(conn,sqlquery);
                    y = fetch(y);
                    y=y.Data;
                    v{i} = cell2mat(y);
                    %close(conn);
                end
                %scatter(x,v1)
                
                plot(app.UIAxes2,x,v{1},'-s',x,v{2},'-s',x,v{3},'-s',x,v{4},'-s',x,v{5},'-s',x,v{6},'-s',x,v{7},'-s',x,v{8},'-s',x,v{9},'-s',x,v{10},'-s',x,v{11},'-s',x,v{12},'-s')
            end
            %title('Concentração de co2')
            %xlabel('Amostra:')
            %ylabel('PPM')
            legend(app.UIAxes2,'show')
            legend(app.UIAxes2,'Reator 1','Reator 2','Reator 3','Reator 4','Reator 5','Reator 6','Reator 7','Reator 8','Reator 9','Reator 10','Reator 11','Reator 12')
            
            
        end

        % Selection change function: mOnOff
        function mOnOffSelectionChanged(app, event)
            selectedTab = app.mOnOff.SelectedTab;
            if strcmp(selectedTab.Title,'Manual')
                app.Status.Value='Modo Manual. Inicializando dispositivos...';
                app.radiovisible=1;
                radioBox(app);
                rotina_manual_gui;
                
            else
                app.Status.Value='Modo Automático.';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Sistema de biodegração de Polímeros';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Title = 'Principal';
            app.Label12 = uilabel(app.Tab3);
            app.Label12.Text = 'Sistema de Biodegradação de Polímeros';
            app.Panel3 = uipanel(app.Tab3);
            app.Panel3.Title = 'Panel';
            app.LigarSwitchLabel = uilabel(app.Panel3);
            app.LigarSwitchLabel.Text = 'Ligar';
            app.LigarSwitch = uiswitch(app.Panel3, 'rocker');
            app.LigarSwitch.ValueChangedFcn = createCallbackFcn(app, @LigarSwitchValueChanged, true);
            app.LabelGauge = uilabel(app.Panel3);
            app.LabelGauge.Text = 'Umidade do Sistema';
            app.GaugeUmidade = uigauge(app.Panel3, 'circular');
            app.GaugeUmidade.Limits = [0 1000];
            app.GaugeUmidade.MajorTicks = [0 250 500 750 1000];
            app.temp = uieditfield(app.Panel3, 'numeric');
            app.temp.Editable = 'off';
            app.temp.Value = 58;
            app.TempCLabel = uilabel(app.Panel3);
            app.TempCLabel.Text = 'Temp (°C)';
            app.EnsaiosLabel = uilabel(app.Panel3);
            app.EnsaiosLabel.Text = 'Ensaios';
            app.medidas = uieditfield(app.Panel3, 'numeric');
            app.medidas.Editable = 'off';
            app.timoEnsaioLabel = uilabel(app.Panel3);
            app.timoEnsaioLabel.Text = 'Útimo Ensaio';
            app.horaField = uieditfield(app.Panel3, 'text');
            app.Panel = uipanel(app.Tab3);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            app.mOnOff = uitabgroup(app.Tab3);
            app.mOnOff.SelectionChangedFcn = createCallbackFcn(app, @mOnOffSelectionChanged, true);
            app.Tab = uitab(app.mOnOff);
            app.Tab.Title = 'Automático';
            app.LabelLamp1 = uilabel(app.Tab);
            app.LabelLamp1.Enable = 'off';
            app.LabelLamp1.Text = 'Reator 1';
            app.Lamp1 = uilamp(app.Tab);
            app.Lamp1.Enable = 'off';
            app.LabelLamp2 = uilabel(app.Tab);
            app.LabelLamp2.Enable = 'off';
            app.LabelLamp2.Text = 'Reator  2';
            app.Lamp2 = uilamp(app.Tab);
            app.Lamp2.Enable = 'off';
            app.LabelLamp3 = uilabel(app.Tab);
            app.LabelLamp3.Enable = 'off';
            app.LabelLamp3.Text = 'Reator  3';
            app.Lamp3 = uilamp(app.Tab);
            app.Lamp3.Enable = 'off';
            app.LabelLamp4 = uilabel(app.Tab);
            app.LabelLamp4.Enable = 'off';
            app.LabelLamp4.Text = 'Reator  4';
            app.Lamp4 = uilamp(app.Tab);
            app.Lamp4.Enable = 'off';
            app.LabelLamp5 = uilabel(app.Tab);
            app.LabelLamp5.Enable = 'off';
            app.LabelLamp5.Text = 'Reator  5';
            app.Lamp5 = uilamp(app.Tab);
            app.Lamp5.Enable = 'off';
            app.LabelLamp6 = uilabel(app.Tab);
            app.LabelLamp6.Enable = 'off';
            app.LabelLamp6.Text = 'Reator  6';
            app.Lamp6 = uilamp(app.Tab);
            app.Lamp6.Enable = 'off';
            app.LabelLamp7 = uilabel(app.Tab);
            app.LabelLamp7.Enable = 'off';
            app.LabelLamp7.Text = 'Reator  7';
            app.Lamp7 = uilamp(app.Tab);
            app.Lamp7.Enable = 'off';
            app.LabelLamp8 = uilabel(app.Tab);
            app.LabelLamp8.Enable = 'off';
            app.LabelLamp8.Text = 'Reator  8';
            app.Lamp8 = uilamp(app.Tab);
            app.Lamp8.Enable = 'off';
            app.LabelLamp9 = uilabel(app.Tab);
            app.LabelLamp9.Enable = 'off';
            app.LabelLamp9.Text = 'Reator 9';
            app.Lamp9 = uilamp(app.Tab);
            app.Lamp9.Enable = 'off';
            app.LabelLamp10 = uilabel(app.Tab);
            app.LabelLamp10.Enable = 'off';
            app.LabelLamp10.Text = 'Reator  10';
            app.Lamp10 = uilamp(app.Tab);
            app.Lamp10.Enable = 'off';
            app.LabelLamp11 = uilabel(app.Tab);
            app.LabelLamp11.Enable = 'off';
            app.LabelLamp11.Text = 'Reator  11';
            app.Lamp11 = uilamp(app.Tab);
            app.Lamp11.Enable = 'off';
            app.LabelLamp12 = uilabel(app.Tab);
            app.LabelLamp12.Enable = 'off';
            app.LabelLamp12.Text = 'Reator  12';
            app.Lamp12 = uilamp(app.Tab);
            app.Lamp12.Enable = 'off';
            app.Tab2 = uitab(app.mOnOff);
            app.Tab2.Title = 'Manual';
            app.ButtonGroup = uibuttongroup(app.Tab2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Reator1M = uiradiobutton(app.ButtonGroup);
            app.Reator1M.Enable = 'off';
            app.Reator1M.Text = 'Reator 1';
            app.Reator1M.Value = true;
            app.Reator2M = uiradiobutton(app.ButtonGroup);
            app.Reator2M.Enable = 'off';
            app.Reator2M.Text = 'Reator 2';
            app.Reator3M = uiradiobutton(app.ButtonGroup);
            app.Reator3M.Enable = 'off';
            app.Reator3M.Text = 'Reator 3';
            app.Reator4M = uiradiobutton(app.ButtonGroup);
            app.Reator4M.Enable = 'off';
            app.Reator4M.Text = 'Reator 4';
            app.Reator5M = uiradiobutton(app.ButtonGroup);
            app.Reator5M.Enable = 'off';
            app.Reator5M.Text = 'Reator 5';
            app.Reator6M = uiradiobutton(app.ButtonGroup);
            app.Reator6M.Enable = 'off';
            app.Reator6M.Text = 'Reator 6';
            app.Reator7M = uiradiobutton(app.ButtonGroup);
            app.Reator7M.Enable = 'off';
            app.Reator7M.Text = 'Reator 7';
            app.Reator8M = uiradiobutton(app.ButtonGroup);
            app.Reator8M.Enable = 'off';
            app.Reator8M.Text = 'Reator 8';
            app.Reator9M = uiradiobutton(app.ButtonGroup);
            app.Reator9M.Enable = 'off';
            app.Reator9M.Text = 'Reator 9';
            app.Reator10M = uiradiobutton(app.ButtonGroup);
            app.Reator10M.Enable = 'off';
            app.Reator10M.Text = 'Reator 10';
            app.Reator11M = uiradiobutton(app.ButtonGroup);
            app.Reator11M.Enable = 'off';
            app.Reator11M.Text = 'Reator 11';
            app.Reator12M = uiradiobutton(app.ButtonGroup);
            app.Reator12M.Enable = 'off';
            app.Reator12M.Text = 'Reator 12';
            app.Panel2 = uipanel(app.Tab3);
            app.Panel2.Title = 'Panel';
            app.LabelTextArea = uilabel(app.Panel2);
            app.LabelTextArea.Text = '';
            app.Status = uitextarea(app.Panel2);
            app.Status.Editable = 'off';
            app.Status.Value = {'Status..'};
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Title = 'Gráfico';
            app.UIAxes2 = uiaxes(app.Tab4);
            title(app.UIAxes2, 'Concentração de co2')
            xlabel(app.UIAxes2, 'Amostras')
            ylabel(app.UIAxes2, 'PPM')
            app.Bplot = uibutton(app.Tab4, 'push');
            app.Bplot.ButtonPushedFcn = createCallbackFcn(app, @bPloclick, true);
            app.Bplot.Text = 'Plotar';
            app.Label13 = uilabel(app.Tab4);
            app.Label13.Text = 'Ponto Final';
            app.NumericEditField2 = uieditfield(app.Tab4, 'numeric');
            app.NumericEditField2.ValueDisplayFormat = '%.0f';
            app.LabelNumericEditField = uilabel(app.Tab4);
            app.LabelNumericEditField.Text = 'Ponto Inicial';
            app.NumericEditField1 = uieditfield(app.Tab4, 'numeric');
            app.NumericEditField1.ValueDisplayFormat = '%.0f';
            app.Tab5 = uitab(app.TabGroup2);
            app.Tab5.Title = 'Dados';
            app.Button3 = uibutton(app.Tab5, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed, true);
            app.Button3.Text = 'Atualizar';
            app.UITable = uitable(app.Tab5);
            app.UITable.ColumnName = {'ID'; 'Umidade'; 'Horário Inicial'; 'Reator 1'; 'Reator 2'; 'Reator 3'; 'Reator 4'; 'Reator 5'; 'Reator 6'; 'Reator 7'; 'Reator 8'; 'Reator 9'; 'Reator 10'; 'Reator 11'; 'Reator 12'; 'Horário Final'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false false false false false false false false false false false false true];
            app.PontoInicialEditFieldLabel = uilabel(app.Tab5);
            app.PontoInicialEditFieldLabel.Text = 'Ponto Inicial';
            app.PontoInicialEditField = uieditfield(app.Tab5, 'numeric');
            app.PontoFinalEditFieldLabel = uilabel(app.Tab5);
            app.PontoFinalEditFieldLabel.Text = 'Ponto Final';
            app.PontoFinalEditField = uieditfield(app.Tab5, 'numeric');
            app.EditField4Label = uilabel(app.Tab5);
            app.EditField4Label.Text = 'Edit Field4';
            app.ExportField = uieditfield(app.Tab5, 'text');
            app.bExport = uibutton(app.Tab5, 'push');
            app.bExport.ButtonPushedFcn = createCallbackFcn(app, @bExportButtonPushed, true);
            app.bExport.Text = 'Button5';
            app.Tab6 = uitab(app.TabGroup2);
            app.Tab6.Title = 'Análise de dados';
            app.Tab7 = uitab(app.TabGroup2);
            app.Tab7.Title = 'Configurações';
            app.Batualizar1 = uibutton(app.Tab7, 'push');
            app.Batualizar1.ButtonPushedFcn = createCallbackFcn(app, @Batualizar1ButtonPushed, true);
            app.Batualizar1.Text = 'Button2';
            app.ListBoxLabel = uilabel(app.Tab7);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.Tab7);
            app.ListBox.Items = {'Criar Tabelas', 'Deletar Tabelas', ''};
            app.ListBox.Value = '';
            app.Bluetooth1EditFieldLabel = uilabel(app.Tab7);
            app.Bluetooth1EditFieldLabel.Text = 'Bluetooth1';
            app.Bluetooth1EditField = uieditfield(app.Tab7, 'text');
            app.Bluetooth2EditFieldLabel = uilabel(app.Tab7);
            app.Bluetooth2EditFieldLabel.Text = 'Bluetooth2';
            app.Bluetooth2EditField = uieditfield(app.Tab7, 'text');
            app.Button3_2 = uibutton(app.Tab7, 'push');
            app.Button3_2.ButtonPushedFcn = createCallbackFcn(app, @Button3_2Pushed, true);
            app.Button3_2.Text = 'Button3';
            app.Button3_3 = uibutton(app.Tab7, 'push');
            app.Button3_3.Text = 'Button3';
            app.WarmupEditFieldLabel = uilabel(app.Tab7);
            app.WarmupEditFieldLabel.Text = 'Warmup';
            app.WarmupEditField = uieditfield(app.Tab7, 'numeric');
            app.Button4 = uibutton(app.Tab7, 'push');
            app.Button4.Text = 'Button4';
            app.Button4_2 = uibutton(app.Tab7, 'push');
            app.Button4_2.Text = 'Button4';
            app.DelayGasEditFieldLabel = uilabel(app.Tab7);
            app.DelayGasEditFieldLabel.Text = 'Delay Gas';
            app.DelayGasEditField = uieditfield(app.Tab7, 'numeric');
            app.DelayFlushEditFieldLabel = uilabel(app.Tab7);
            app.DelayFlushEditFieldLabel.Text = 'Delay Flush';
            app.DelayFlushEditField = uieditfield(app.Tab7, 'numeric');
            app.DelayFlushv1EditFieldLabel = uilabel(app.Tab7);
            app.DelayFlushv1EditFieldLabel.Text = 'Delay Flush v1';
            app.DelayFlushv1EditField = uieditfield(app.Tab7, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end