classdef gui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % Sistema ...
        TabGroup2             matlab.ui.container.TabGroup       % Main, Pl...
        Tab3                  matlab.ui.container.Tab            % Main
        Label12               matlab.ui.control.Label            % Sistema ...
        Panel3                matlab.ui.container.Panel          % Panel
        LabelRockerSwitch     matlab.ui.control.Label            % Rocker S...
        OnOff                 matlab.ui.control.RockerSwitch     % Off, On
        LabelGauge            matlab.ui.control.Label            % Umidade ...
        Gauge                 matlab.ui.control.Gauge            % [0 100]
        Panel                 matlab.ui.container.Panel          % Panel
        Button                matlab.ui.control.Button           % Plot
        LabelNumericEditField matlab.ui.control.Label            % Ponto In...
        NumericEditField      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label13               matlab.ui.control.Label            % Ponto Final
        NumericEditField2     matlab.ui.control.NumericEditField % [-Inf Inf]
        Grafico               matlab.ui.control.UIAxes           % Title
        TabGroup              matlab.ui.container.TabGroup       % Automáti...
        Tab                   matlab.ui.container.Tab            % Automático
        LabelLamp             matlab.ui.control.Label            % Reator 1
        Reator1               matlab.ui.control.Lamp            
        Label                 matlab.ui.control.Label            % Reator  2
        Reator2               matlab.ui.control.Lamp            
        Label2                matlab.ui.control.Label            % Reator  3
        Reator3               matlab.ui.control.Lamp            
        Label3                matlab.ui.control.Label            % Reator  4
        Reator4               matlab.ui.control.Lamp            
        Label4                matlab.ui.control.Label            % Reator  5
        Reator5               matlab.ui.control.Lamp            
        Label5                matlab.ui.control.Label            % Reator  6
        Reator6               matlab.ui.control.Lamp            
        Label6                matlab.ui.control.Label            % Reator  7
        Reator7               matlab.ui.control.Lamp            
        Label7                matlab.ui.control.Label            % Reator  8
        Reator8               matlab.ui.control.Lamp            
        Label8                matlab.ui.control.Label            % Reator 9
        Reator9               matlab.ui.control.Lamp            
        Label9                matlab.ui.control.Label            % Reator  10
        Reator10              matlab.ui.control.Lamp            
        Label10               matlab.ui.control.Label            % Reator  11
        Reator11              matlab.ui.control.Lamp            
        Label11               matlab.ui.control.Label            % Reator  12
        Reator12              matlab.ui.control.Lamp            
        Tab2                  matlab.ui.container.Tab            % Manual
        ButtonGroup           matlab.ui.container.ButtonGroup    % Button G...
        RadioButton           matlab.ui.control.RadioButton      % Reator 1
        RadioButton2          matlab.ui.control.RadioButton      % Reator 2
        RadioButton3          matlab.ui.control.RadioButton      % Reator 3
        RadioButton4          matlab.ui.control.RadioButton      % Reator 4
        RadioButton5          matlab.ui.control.RadioButton      % Reator 5
        RadioButton6          matlab.ui.control.RadioButton      % Reator 6
        RadioButton7          matlab.ui.control.RadioButton      % Reator 7
        RadioButton8          matlab.ui.control.RadioButton      % Reator 8
        RadioButton9          matlab.ui.control.RadioButton      % Reator 9
        RadioButton10         matlab.ui.control.RadioButton      % Reator 10
        RadioButton11         matlab.ui.control.RadioButton      % Reator 11
        RadioButton12         matlab.ui.control.RadioButton      % Reator 12
        Panel2                matlab.ui.container.Panel          % Panel
        LabelTextArea         matlab.ui.control.Label           
        Status                matlab.ui.control.TextArea         % Estatus..
        Tab4                  matlab.ui.container.Tab            % Plot
        Tab5                  matlab.ui.container.Tab            % Dados
        LabelEditField        matlab.ui.control.Label            % Dias de ...
        EditField             matlab.ui.control.EditField       
        Label14               matlab.ui.control.Label            % Temp. do...
        EditField2            matlab.ui.control.EditField        % 58
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function bPloclick(app)
            co2plot2;
            
        end

        % OnOff value changed function
        function OnOffValueChanged(app)
            value = app.OnOff.Value;
            %app.Button2.BackgroundColor = [0.128 0.255 0];
            if strcmp(value,'On')
                %if value ==
                app.Reator1.Enable = 'on';
                app.LabelLamp.Enable = 'on';
                app.Reator1.Color = [0 1 0];
                app.Status.Value = 'ligou';
            else
                app.Reator1.Enable = 'off';
                app.LabelLamp.Enable = 'off';
                app.Reator1.Color = [1 0 0];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Sistema de biodegração de Polímeros';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Units = 'pixels';
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Main';
            app.Label12 = uilabel(app.Tab3);
            app.Label12.Text = 'Sistema de Biodegradação de Polímeros';
            app.Panel3 = uipanel(app.Tab3);
            app.Panel3.Title = 'Panel';
            app.Panel3.Units = 'pixels';
            app.LabelRockerSwitch = uilabel(app.Panel3);
            app.LabelRockerSwitch.Text = 'Rocker Switch';
            app.OnOff = uiswitch(app.Panel3, 'rocker');
            app.OnOff.ValueChangedFcn = createCallbackFcn(app, @OnOffValueChanged);
            app.LabelGauge = uilabel(app.Panel3);
            app.LabelGauge.Text = 'Umidade do Sistema';
            app.Gauge = uigauge(app.Panel3, 'circular');
            app.Label14 = uilabel(app.Panel3);
            app.Label14.Text = 'Temp. do Sistema';
            app.EditField2 = uieditfield(app.Panel3, 'text');
            app.EditField2.Value = '58';
            app.LabelEditField = uilabel(app.Panel3);
            app.LabelEditField.Text = 'Dias de incubação';
            app.EditField = uieditfield(app.Panel3, 'text');
            app.Panel = uipanel(app.Tab3);
            app.Panel.Title = 'Panel';
            app.Panel.Units = 'pixels';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @bPloclick);
            app.Button.Text = 'Plot';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Ponto Inicial';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.Label13 = uilabel(app.Panel);
            app.Label13.Text = 'Ponto Final';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.Grafico = uiaxes(app.Panel);
            title(app.Grafico, 'Title');
            xlabel(app.Grafico, 'Medidas');
            ylabel(app.Grafico, 'PPM');
            app.TabGroup = uitabgroup(app.Tab3);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Automático';
            app.LabelLamp = uilabel(app.Tab);
            app.LabelLamp.Enable = 'off';
            app.LabelLamp.Text = 'Reator 1';
            app.Reator1 = uilamp(app.Tab);
            app.Reator1.Enable = 'off';
            app.Label = uilabel(app.Tab);
            app.Label.Enable = 'off';
            app.Label.Text = 'Reator  2';
            app.Reator2 = uilamp(app.Tab);
            app.Reator2.Enable = 'off';
            app.Label2 = uilabel(app.Tab);
            app.Label2.Enable = 'off';
            app.Label2.Text = 'Reator  3';
            app.Reator3 = uilamp(app.Tab);
            app.Reator3.Enable = 'off';
            app.Label3 = uilabel(app.Tab);
            app.Label3.Enable = 'off';
            app.Label3.Text = 'Reator  4';
            app.Reator4 = uilamp(app.Tab);
            app.Reator4.Enable = 'off';
            app.Label4 = uilabel(app.Tab);
            app.Label4.Enable = 'off';
            app.Label4.Text = 'Reator  5';
            app.Reator5 = uilamp(app.Tab);
            app.Reator5.Enable = 'off';
            app.Label5 = uilabel(app.Tab);
            app.Label5.Enable = 'off';
            app.Label5.Text = 'Reator  6';
            app.Reator6 = uilamp(app.Tab);
            app.Reator6.Enable = 'off';
            app.Label6 = uilabel(app.Tab);
            app.Label6.Enable = 'off';
            app.Label6.Text = 'Reator  7';
            app.Reator7 = uilamp(app.Tab);
            app.Reator7.Enable = 'off';
            app.Label7 = uilabel(app.Tab);
            app.Label7.Enable = 'off';
            app.Label7.Text = 'Reator  8';
            app.Reator8 = uilamp(app.Tab);
            app.Reator8.Enable = 'off';
            app.Label8 = uilabel(app.Tab);
            app.Label8.Enable = 'off';
            app.Label8.Text = 'Reator 9';
            app.Reator9 = uilamp(app.Tab);
            app.Reator9.Enable = 'off';
            app.Label9 = uilabel(app.Tab);
            app.Label9.Enable = 'off';
            app.Label9.Text = 'Reator  10';
            app.Reator10 = uilamp(app.Tab);
            app.Reator10.Enable = 'off';
            app.Label10 = uilabel(app.Tab);
            app.Label10.Enable = 'off';
            app.Label10.Text = 'Reator  11';
            app.Reator11 = uilamp(app.Tab);
            app.Reator11.Enable = 'off';
            app.Label11 = uilabel(app.Tab);
            app.Label11.Enable = 'off';
            app.Label11.Text = 'Reator  12';
            app.Reator12 = uilamp(app.Tab);
            app.Reator12.Enable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Manual';
            app.ButtonGroup = uibuttongroup(app.Tab2);
            app.ButtonGroup.Title = 'Button Group';
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Reator 1';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Reator 2';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup);
            app.RadioButton3.Text = 'Reator 3';
            app.RadioButton4 = uiradiobutton(app.ButtonGroup);
            app.RadioButton4.Text = 'Reator 4';
            app.RadioButton5 = uiradiobutton(app.ButtonGroup);
            app.RadioButton5.Text = 'Reator 5';
            app.RadioButton6 = uiradiobutton(app.ButtonGroup);
            app.RadioButton6.Text = 'Reator 6';
            app.RadioButton7 = uiradiobutton(app.ButtonGroup);
            app.RadioButton7.Text = 'Reator 7';
            app.RadioButton8 = uiradiobutton(app.ButtonGroup);
            app.RadioButton8.Text = 'Reator 8';
            app.RadioButton9 = uiradiobutton(app.ButtonGroup);
            app.RadioButton9.Text = 'Reator 9';
            app.RadioButton10 = uiradiobutton(app.ButtonGroup);
            app.RadioButton10.Text = 'Reator 10';
            app.RadioButton11 = uiradiobutton(app.ButtonGroup);
            app.RadioButton11.Text = 'Reator 11';
            app.RadioButton12 = uiradiobutton(app.ButtonGroup);
            app.RadioButton12.Text = 'Reator 12';
            app.Panel2 = uipanel(app.Tab3);
            app.Panel2.Title = 'Panel';
            app.Panel2.Units = 'pixels';
            app.LabelTextArea = uilabel(app.Panel2);
            app.LabelTextArea.Text = '';
            app.Status = uitextarea(app.Panel2);
            app.Status.Editable = 'off';
            app.Status.Value = {'Estatus..'};
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Plot';
            app.Tab5 = uitab(app.TabGroup2);
            app.Tab5.Units = 'pixels';
            app.Tab5.Title = 'Dados';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end