classdef gui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                    % Siste...
        TabGroup2               matlab.ui.container.TabGroup        % Main,...
        Tab3                    matlab.ui.container.Tab             % Main
        Label12                 matlab.ui.control.Label             % Siste...
        Panel3                  matlab.ui.container.Panel           % Panel
        LabelRockerSwitch       matlab.ui.control.Label             % Rocke...
        RockerSwitch            matlab.ui.control.RockerSwitch      % Off, On
        LabelNinetyDegreeGauge  matlab.ui.control.Label             % Tempe...
        NinetyDegreeGauge       matlab.ui.control.NinetyDegreeGauge % [15 90]
        LabelGauge              matlab.ui.control.Label             % Umida...
        Gauge                   matlab.ui.control.Gauge             % [0 100]
        LabelNinetyDegreeGauge2 matlab.ui.control.Label             % Dias ...
        NinetyDegreeGauge2      matlab.ui.control.NinetyDegreeGauge % [0 100]
        Panel                   matlab.ui.container.Panel           % Panel
        Button                  matlab.ui.control.Button            % Plot
        LabelNumericEditField   matlab.ui.control.Label             % Ponto...
        NumericEditField        matlab.ui.control.NumericEditField  % [-Inf...
        Label13                 matlab.ui.control.Label             % Ponto...
        NumericEditField2       matlab.ui.control.NumericEditField  % [-Inf...
        UIAxes                  matlab.ui.control.UIAxes            % Title
        TabGroup                matlab.ui.container.TabGroup        % Autom...
        Tab                     matlab.ui.container.Tab             % Autom...
        LabelLamp1              matlab.ui.control.Label             % Reator 1
        Lamp1                   matlab.ui.control.Lamp             
        LabelLamp2              matlab.ui.control.Label             % Reato...
        Lamp2                   matlab.ui.control.Lamp             
        LabelLamp3              matlab.ui.control.Label             % Reato...
        Lamp3                   matlab.ui.control.Lamp             
        LabelLamp4              matlab.ui.control.Label             % Reato...
        Lamp4                   matlab.ui.control.Lamp             
        LabelLamp5              matlab.ui.control.Label             % Reato...
        Lamp5                   matlab.ui.control.Lamp             
        LabelLamp6              matlab.ui.control.Label             % Reato...
        Lamp6                   matlab.ui.control.Lamp             
        LabelLamp7              matlab.ui.control.Label             % Reato...
        Lamp7                   matlab.ui.control.Lamp             
        LabelLamp8              matlab.ui.control.Label             % Reato...
        Lamp8                   matlab.ui.control.Lamp             
        LabelLamp9              matlab.ui.control.Label             % Reator 9
        Lamp9                   matlab.ui.control.Lamp             
        LabelLamp10             matlab.ui.control.Label             % Reato...
        Lamp10                  matlab.ui.control.Lamp             
        LabelLamp11             matlab.ui.control.Label             % Reato...
        Lamp11                  matlab.ui.control.Lamp             
        LabelLamp12             matlab.ui.control.Label             % Reato...
        Lamp12                  matlab.ui.control.Lamp             
        Tab2                    matlab.ui.container.Tab             % Manual
        ButtonGroup             matlab.ui.container.ButtonGroup     % Butto...
        RadioButton             matlab.ui.control.RadioButton       % Reator 1
        RadioButton2            matlab.ui.control.RadioButton       % Reator 2
        RadioButton3            matlab.ui.control.RadioButton       % Reator 3
        RadioButton4            matlab.ui.control.RadioButton       % Reator 4
        RadioButton5            matlab.ui.control.RadioButton       % Reator 5
        RadioButton6            matlab.ui.control.RadioButton       % Reator 6
        RadioButton7            matlab.ui.control.RadioButton       % Reator 7
        RadioButton8            matlab.ui.control.RadioButton       % Reator 8
        RadioButton9            matlab.ui.control.RadioButton       % Reator 9
        RadioButton10           matlab.ui.control.RadioButton       % Reato...
        RadioButton11           matlab.ui.control.RadioButton       % Reato...
        RadioButton12           matlab.ui.control.RadioButton       % Reato...
        Panel2                  matlab.ui.container.Panel           % Panel
        LabelTextArea           matlab.ui.control.Label            
        Status                  matlab.ui.control.TextArea          % Estat...
        Tab4                    matlab.ui.container.Tab             % Plot
        Tab5                    matlab.ui.container.Tab             % Dados
    end

    
    methods (Access = private)
        
        function results = rotina(app)
            disp('Inicio da Rotina')
            ME = []; %cria a matriz de tratamento de erro
            try %tratamento de erro
                %arquivos % rotina que cria os arquivos txt sem apagar os conteúdos.
                %arquivosDEL % rotina que cria os arquivos txt apagando os conteudos (deixar coment.)
                i=0; %contador para os reatores
                i2=0;%contador da quantidade de medidas (0 padrão)
                while i2<1
                    %##########################################################################################
                    %#Este laço é responsável pela comutação dos reatores e chamada das rotinas dos programas.#
                    %##########################################################################################
                    while i<12
                        i=i+1; %contador
                        iS = int2str(i); %converter o contador em uma string
                        str='v'; %string padrao
                        str = strcat(str,iS); % concatena as duas strings;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % Abre as comunicações Seriais com os dispositivos e aciona os reatores   %
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        
                        if strcmp(str,'v1') % Reator 1
                            %app.Status.Value='Configurando Conexão Serial..';
                            if size(instrfind)>0 %Limpa as COM e objetos caso existam
                                all = instrfindall;
                                fclose(all);
                                delete(all);
                                clear all;
                            end
                            app.Status.Value='Inicializando Bluetooth do Controlador..';
                            %try
                            %     arduino = serial('COM3'); %cria o objeto arduino
                            %     set(arduino,'BaudRate',9600); %baudrate arduino
                            arduino = Bluetooth('HC-06', 1);
                            fopen(arduino); %abre a comunicação serial o arduino
                            pause(3);
                            %catch
                            %   disp('Porta do Arduino não reconhecida')
                            %   dos('reiniciar.bat');
                            %end
                            %sensor
                            app.Status.Value='Inicializando Bluetooth do Sensor Co2..';
                            % try
                            %T6615 = serial('COM5'); %cria o objeto do sensor co2
                            %set(T6615,'BaudRate',19200); %baudrate sensor
                            %b = instrhwinfo('Bluetooth');
                            % b.RemoteNames
                            %instrhwinfo('Bluetooth', RemoteName);
                            T6615 = Bluetooth('SAAB', 1);
                            fopen(T6615); %abre a comunicação
                            pause(3);
                            
                            %catch
                            %disp('Porta do sensor não reconhecida')
                            %dos('reiniciar.bat');
                            %end
                            T6615.ReadAsyncMode = 'manual'; %define o modo de comunicação como assíncrono
                            
                        end
                        %fim do serial.
                        %#######################
                        %# ROTINA DAS LEITURAS #
                        %#######################
                        delaywarmup = 7;
                        delaygas = 7;
                        delayflush = 7;
                        delayflushv1 = 7;
                        if strcmp(str,'v1')
                            enviarhex = hex2dec({'FF','FE','02','B9','02'}); %comando para sair do modo sleep.
                            fwrite(T6615,enviarhex); %envia para o sensor os bytes do enviarhex
                            pause(5);
                            enviarhex = hex2dec({'FF','FE','01','84'}); %Warmup.
                            fwrite(T6615,enviarhex); %envia para o sensor os bytes do enviarhex
                            app.Status.Value='Aquecimento do Sensor 5 min..';
                            %tempo de warmup
                            pause(delaywarmup); %warmup
                            enviarhex = hex2dec({'FF','FE','01','BD'}); %comando para iniciar as leituras
                            fwrite(T6615,enviarhex);
                            pause(2);
                            flushinput(T6615);
                            pause(2);
                            umidade='umidade';
                            fprintf(arduino,'%s\n',umidade);
                            pause(2);
                            out =fgetl(arduino);
                            app.Status.Value=sprintf('A Umidade do Sistema é %s',out);
                            umidade=str2double(out);
                            tu = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                        end
                        flushinput(arduino);
                        pause(2);
                        fprintf(arduino,'%s\n',str); %envia v1, v2,v3.. para o arduino acionar o reator desejável.
                        pause(2);
                        str=fgetl(arduino); % Recebe o valor v1,v2,v3 diretamente do arduino. (confirmação)
                        app.Status.Value=sprintf('Reator %s',str);
                        
                        if strcmp(str,'v1')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp1.Enable = 'on';
                            app.LabelLamp1.Enable = 'on';
                            app.Lamp1.Color = [0 1 0];
                            
                            app.Lamp12.Enable = 'off';
                            app.LabelLamp12.Enable = 'off';
                            app.Lamp12.Color = [1 0 0];
                        end
                        if strcmp(str,'v2')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp2.Enable = 'on';
                            app.LabelLamp2.Enable = 'on';
                            app.Lamp2.Color = [0 1 0];
                            
                            app.Lamp1.Enable = 'off';
                            app.LabelLamp1.Enable = 'off';
                            app.Lamp1.Color = [1 0 0];
                        end
                        if strcmp(str,'v3')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp3.Enable = 'on';
                            app.LabelLamp3.Enable = 'on';
                            app.Lamp3.Color = [0 1 0];
                            
                            app.Lamp2.Enable = 'off';
                            app.LabelLamp2.Enable = 'off';
                            app.Lamp2.Color = [1 0 0];
                        end
                        if strcmp(str,'v4')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp4.Enable = 'on';
                            app.LabelLamp4.Enable = 'on';
                            app.Lamp4.Color = [0 1 0];
                            
                            app.Lamp3.Enable = 'off';
                            app.LabelLamp3.Enable = 'off';
                            app.Lamp3.Color = [1 0 0];
                        end
                        if strcmp(str,'v5')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp5.Enable = 'on';
                            app.LabelLamp5.Enable = 'on';
                            app.Lamp5.Color = [0 1 0];
                            
                            app.Lamp4.Enable = 'off';
                            app.LabelLamp4.Enable = 'off';
                            app.Lamp4.Color = [1 0 0];
                        end
                        if strcmp(str,'v6')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp6.Enable = 'on';
                            app.LabelLamp6.Enable = 'on';
                            app.Lamp6.Color = [0 1 0];
                            
                            app.Lamp5.Enable = 'off';
                            app.LabelLamp5.Enable = 'off';
                            app.Lamp5.Color = [1 0 0];
                        end
                        if strcmp(str,'v7')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp7.Enable = 'on';
                            app.LabelLamp7.Enable = 'on';
                            app.Lamp7.Color = [0 1 0];
                            
                            app.Lamp6.Enable = 'off';
                            app.LabelLamp6.Enable = 'off';
                            app.Lamp6.Color = [1 0 0];
                        end
                        if strcmp(str,'v8')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp8.Enable = 'on';
                            app.LabelLamp8.Enable = 'on';
                            app.Lamp8.Color = [0 1 0];
                            
                            app.Lamp7.Enable = 'off';
                            app.LabelLamp7.Enable = 'off';
                            app.Lamp7.Color = [1 0 0];
                        end
                        if strcmp(str,'v9')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp9.Enable = 'on';
                            app.LabelLamp9.Enable = 'on';
                            app.Lamp9.Color = [0 1 0];
                            
                            app.Lamp8.Enable = 'off';
                            app.LabelLamp8.Enable = 'off';
                            app.Lamp8.Color = [1 0 0];
                        end
                        if strcmp(str,'v10')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp10.Enable = 'on';
                            app.LabelLamp10.Enable = 'on';
                            app.Lamp10.Color = [0 1 0];
                            
                            app.Lamp9.Enable = 'off';
                            app.LabelLamp9.Enable = 'off';
                            app.Lamp9.Color = [1 0 0];
                        end
                        if strcmp(str,'v11')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp11.Enable = 'on';
                            app.LabelLamp11.Enable = 'on';
                            app.Lamp11.Color = [0 1 0];
                            
                            app.Lamp10.Enable = 'off';
                            app.LabelLamp10.Enable = 'off';
                            app.Lamp10.Color = [1 0 0];
                        end
                        if strcmp(str,'v12')
                            %app.Button2.BackgroundColor = [0.128 0.255 0];
                            app.Lamp12.Enable = 'on';
                            app.LabelLamp12.Enable = 'on';
                            app.Lamp12.Color = [0 1 0];
                            
                            app.Lamp11.Enable = 'off';
                            app.LabelLamp11.Enable = 'off';
                            app.Lamp11.Color = [1 0 0];
                        end
                        
                        pause(delaygas);
                        
                        app.Status.Value='Iniciando Leituras..';
                        n1=0;
                        n=0;
                        a=0;
                        readasync(T6615,5);
                        pause(5);
                        
                        %Requisita a umidade e hora do sistema
                        
                        %Leituras
                        while n1<=0
                            while n<20
                                
                                if T6615.BytesAvailable > 0 %dados disponivel  na serial
                                    leitura= fread(T6615,5,'uint8'); %leitura = dados do sensor
                                    pause(3);
                                    if length(leitura)==5 %tamanho padrão da leitura do sensor 5 bytes
                                        p1 = dec2hex(leitura(5));  %converte os ultimos 2 bytes para decimal
                                        p2 = dec2hex(leitura(4));
                                        s = strcat(p2,p1); %junta os bytes dos dados do ppm 2 bytes
                                        n=n+1;
                                        ppm(n) = hex2dec(s); %converte os bytes em decimal
                                        ppm(n)
                                        flushinput(arduino);
                                        fprintf(arduino,'%d\n',ppm(n));
                                        %stopasync(T6615);
                                        readasync(T6615,5); %faz uma nova leitura do sensor
                                        if ppm(n)<280 % se a leitura for menor que 200 ppm, repete a leitura
                                            n=n-1;
                                        else
                                            a=a+1;
                                            app.Status.Value=sprintf('%d - PPM: %d',a,ppm(n));
                                        end
                                    end
                                end
                                
                            end
                            n1=n1+1;
                            stopasync(T6615)
                        end
                        h=0;
                        ppmM=0;
                        ppmM0=0;
                        while h < 10
                            h=h+1;
                            ppmM0 = (ppmM0 + ppm(h));
                        end
                        ppmM0 = ppmM0/h
                        
                        while h < 20
                            h=h+1;
                            ppmM = (ppmM + ppm(h));
                        end
                        h=h-10;
                        ppmM = ppmM/h
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % Hora só para o txt
                        t = datetime('now'); %t = data.
                        t = datestr(t);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        %Grava os valores da média e hora de cada reator em variaveis e envia para
                        %o arduino
                        if strcmp(str,'v1')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r1'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1); % Envia para o arduino a média da leitura
                            pause(1);
                            ppmM0_1 = ppmM0;
                            ppmM_1 = ppmM;
                            t1 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                            
                        end
                        if strcmp(str,'v2')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r2'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_2 = ppmM0;
                            ppmM_2 = ppmM;
                            t2 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                        end
                        if strcmp(str,'v3')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r3'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_3 = ppmM0;
                            ppmM_3 = ppmM;
                            t3 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                        end
                        if strcmp(str,'v4')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r4'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_4 = ppmM0;
                            ppmM_4 = ppmM;
                            t4 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                            
                        end
                        if strcmp(str,'v5')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r5'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_5 = ppmM0;
                            ppmM_5 = ppmM;
                            t5 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                            
                        end
                        if strcmp(str,'v6')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r6'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_6 = ppmM0;
                            ppmM_6 = ppmM;
                            t6 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                        end
                        if strcmp(str,'v7')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r7'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_7 = ppmM0;
                            ppmM_7 = ppmM;
                            t7 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                        end
                        if strcmp(str,'v8')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r8'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_8 = ppmM0;
                            ppmM_8 = ppmM;
                            t8 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                        end
                        if strcmp(str,'v9')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='r9'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_9 = ppmM0;
                            ppmM_9 = ppmM;
                            t9 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                            
                        end
                        if strcmp(str,'v10')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='s10'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_10 = ppmM0;
                            ppmM_10 = ppmM;
                            t10 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                            
                        end
                        if strcmp(str,'v11')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='t11'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_11 = ppmM0;
                            ppmM_11 = ppmM;
                            t11 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                            
                        end
                        if strcmp(str,'v12')
                            ppmM2 = int2str(ppmM); %converte o contador em uma string
                            r1='u12'; %string padrao
                            r1 = strcat(r1,ppmM2); % concatena as duas strings;
                            flushinput(arduino);
                            fprintf(arduino,'%s\n',r1);
                            pause(1);
                            ppmM0_12 = ppmM0;
                            ppmM_12 = ppmM;
                            t12 = datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss');
                            app.Lamp12.Enable = 'off';
                            app.LabelLamp12.Enable = 'off';
                            app.Lamp12.Color = [1 0 0];
                        end
                        str='b3'; %b3 relé que interrompe a passagem de ar para o sensor (flush)
                        flushinput(arduino);
                        fprintf(arduino,'%s\n',str); %envia o b3 para o arduino
                        pause(2);
                        
                        str='v1'; %b3 relé que interrompe a passagem de ar para o sensor (flush)
                        flushinput(arduino);
                        fprintf(arduino,'%s\n',str); %envia o b3 para o arduino
                        app.Status.Value='Reator 1 Flush..';
                        pause(delayflushv1);
                        
                        str='b3'; %b3 relé que interrompe a passagem de ar para o sensor (flush)
                        flushinput(arduino);
                        fprintf(arduino,'%s\n',str); %envia o b3 para o arduino
                        app.Status.Value='Flush..';
                        pause(delayflush);
                        %     enviarhex = hex2dec({'FF','FE','01','95'}); %Warmup.
                        %     fwrite(T6615,enviarhex); %envia para o sensor os bytes do enviarhex
                        %     pause(5); %tempo de flush
                        %     enviarhex = hex2dec({'FF','FE','01','BD'}); %comando para iniciar as leituras
                        %     fwrite(T6615,enviarhex);
                        %     flushinput(T6615);
                        %     pause(2);
                        %fim do rotinaleitras
                    end
                    i2 = i2+1; %incrementa i2
                    i=0; %zera i
                end
                %################################################################################################
                %# Envia o comando de standby do sensor e grava os valores da média e hora de cada reator no BD #
                %################################################################################################
                flushinput(T6615);
                pause(2);
                enviarhex = hex2dec({'FF','FE','02','B9','01'});
                fwrite(T6615,enviarhex);
                pause(2);
                flushinput(arduino);
                fprintf(arduino,'%s\n',t);
                pause(2);
                str='fim';
                flushinput(arduino);
                fprintf(arduino,'%s\n',str);
                pause(2);
                if size(instrfind)>0 %Limpa as COM e objetos caso existam
                    all = instrfindall;
                    fclose(all);
                    delete(all);
                    clear all;
                end
                % Envia as variaveis para o BD no final das rotinas
                conn = database('Saab Matlab','root','');
                sqlquery = sprintf( 'INSERT INTO `matlab`.`umidade` (umidade,hour) VALUES(%d,"%s");',umidade,tu);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 1` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_1,ppmM_1,t1);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 2`(ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_2,ppmM_2,t2);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 3`(ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_3,ppmM_3,t3);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 4` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_4,ppmM_4,t4);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 5` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_5,ppmM_5,t5);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 6` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_6,ppmM_6,t6);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 7` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_7,ppmM_7,t7);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 8` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_8,ppmM_8,t8);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 9` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_9,ppmM_9,t9);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 10` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_10,ppmM_10,t10);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 11` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_11,ppmM_11,t11);
                curs = exec(conn,sqlquery);
                
                sqlquery = sprintf( 'INSERT INTO `matlab`.`Reator 12` (ppm0,ppm,hour) VALUES(%.2f,%.2f,"%s");',ppmM0_12,ppmM_12,t12);
                curs = exec(conn,sqlquery);
                
                close(curs);
                close(conn);
                disp('FIM')
                app.Status.Value='Fim da Rotina..'
            catch ME %caso haja algum erro na execução do laço, ME != 0
            end
            if ~isempty(ME) %caso haja algum erro no try, executar o reiniciar.bat
                disp('reiniciar')
                app.Status.Value='Error'
                %dos('reiniciar.bat');
            end
        end
        
        function results = func15(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % cd 'C:\Users\Pedro\Google Drive\Pedro\ifpb\software' %mudar para padrão
            % clear all; % limpar todas as variaveis
            % addpath('C:\Users\Pedro\Google Drive\Pedro\ifpb\software'); %adicionar o path
            % javaaddpath 'C:\Users\pedro\Google Drive\Pedro\ifpb\software\sqljdbc_6.0\enu\jre7\sqljdbc41.jar'
        end

        % Button button pushed function
        function bPloclick(app)
            co2plot2;
            
        end

        % RockerSwitch value changed function
        function RockerSwitchValueChanged(app)
            value = app.RockerSwitch.Value;
            if strcmp(value,'On')
                %app.Button2.BackgroundColor = [0.128 0.255 0];
                rotina(app);
            else
                %app.Button2.BackgroundColor = [0.128 0.255 0];
                app.Lamp1.Enable = 'off';
                app.LabelLamp1.Enable = 'off';
                app.Lamp1.Color = [1 0 0];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Sistema de biodegração de Polímeros';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Units = 'pixels';
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Main';
            app.Label12 = uilabel(app.Tab3);
            app.Label12.Text = 'Sistema de Biodegradação de Polímeros';
            app.Panel3 = uipanel(app.Tab3);
            app.Panel3.Title = 'Panel';
            app.Panel3.Units = 'pixels';
            app.LabelRockerSwitch = uilabel(app.Panel3);
            app.LabelRockerSwitch.Text = 'Rocker Switch';
            app.RockerSwitch = uiswitch(app.Panel3, 'rocker');
            app.RockerSwitch.ValueChangedFcn = createCallbackFcn(app, @RockerSwitchValueChanged);
            app.LabelNinetyDegreeGauge = uilabel(app.Panel3);
            app.LabelNinetyDegreeGauge.Text = 'Temperatura do Sistema';
            app.NinetyDegreeGauge = uigauge(app.Panel3, 'ninetydegree');
            app.NinetyDegreeGauge.Limits = [15 90];
            app.NinetyDegreeGauge.ScaleDirection = 'counterclockwise';
            app.NinetyDegreeGauge.Value = 15;
            app.LabelGauge = uilabel(app.Panel3);
            app.LabelGauge.Text = 'Umidade do Sistema';
            app.Gauge = uigauge(app.Panel3, 'circular');
            app.LabelNinetyDegreeGauge2 = uilabel(app.Panel3);
            app.LabelNinetyDegreeGauge2.Text = 'Dias de incubação';
            app.NinetyDegreeGauge2 = uigauge(app.Panel3, 'ninetydegree');
            app.Panel = uipanel(app.Tab3);
            app.Panel.Title = 'Panel';
            app.Panel.Units = 'pixels';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @bPloclick);
            app.Button.Text = 'Plot';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Ponto Inicial';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.Label13 = uilabel(app.Panel);
            app.Label13.Text = 'Ponto Final';
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'Medidas');
            ylabel(app.UIAxes, 'PPM');
            app.TabGroup = uitabgroup(app.Tab3);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Automático';
            app.LabelLamp1 = uilabel(app.Tab);
            app.LabelLamp1.Enable = 'off';
            app.LabelLamp1.Text = 'Reator 1';
            app.Lamp1 = uilamp(app.Tab);
            app.Lamp1.Enable = 'off';
            app.LabelLamp2 = uilabel(app.Tab);
            app.LabelLamp2.Enable = 'off';
            app.LabelLamp2.Text = 'Reator  2';
            app.Lamp2 = uilamp(app.Tab);
            app.Lamp2.Enable = 'off';
            app.LabelLamp3 = uilabel(app.Tab);
            app.LabelLamp3.Enable = 'off';
            app.LabelLamp3.Text = 'Reator  3';
            app.Lamp3 = uilamp(app.Tab);
            app.Lamp3.Enable = 'off';
            app.LabelLamp4 = uilabel(app.Tab);
            app.LabelLamp4.Enable = 'off';
            app.LabelLamp4.Text = 'Reator  4';
            app.Lamp4 = uilamp(app.Tab);
            app.Lamp4.Enable = 'off';
            app.LabelLamp5 = uilabel(app.Tab);
            app.LabelLamp5.Enable = 'off';
            app.LabelLamp5.Text = 'Reator  5';
            app.Lamp5 = uilamp(app.Tab);
            app.Lamp5.Enable = 'off';
            app.LabelLamp6 = uilabel(app.Tab);
            app.LabelLamp6.Enable = 'off';
            app.LabelLamp6.Text = 'Reator  6';
            app.Lamp6 = uilamp(app.Tab);
            app.Lamp6.Enable = 'off';
            app.LabelLamp7 = uilabel(app.Tab);
            app.LabelLamp7.Enable = 'off';
            app.LabelLamp7.Text = 'Reator  7';
            app.Lamp7 = uilamp(app.Tab);
            app.Lamp7.Enable = 'off';
            app.LabelLamp8 = uilabel(app.Tab);
            app.LabelLamp8.Enable = 'off';
            app.LabelLamp8.Text = 'Reator  8';
            app.Lamp8 = uilamp(app.Tab);
            app.Lamp8.Enable = 'off';
            app.LabelLamp9 = uilabel(app.Tab);
            app.LabelLamp9.Enable = 'off';
            app.LabelLamp9.Text = 'Reator 9';
            app.Lamp9 = uilamp(app.Tab);
            app.Lamp9.Enable = 'off';
            app.LabelLamp10 = uilabel(app.Tab);
            app.LabelLamp10.Enable = 'off';
            app.LabelLamp10.Text = 'Reator  10';
            app.Lamp10 = uilamp(app.Tab);
            app.Lamp10.Enable = 'off';
            app.LabelLamp11 = uilabel(app.Tab);
            app.LabelLamp11.Enable = 'off';
            app.LabelLamp11.Text = 'Reator  11';
            app.Lamp11 = uilamp(app.Tab);
            app.Lamp11.Enable = 'off';
            app.LabelLamp12 = uilabel(app.Tab);
            app.LabelLamp12.Enable = 'off';
            app.LabelLamp12.Text = 'Reator  12';
            app.Lamp12 = uilamp(app.Tab);
            app.Lamp12.Enable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Manual';
            app.ButtonGroup = uibuttongroup(app.Tab2);
            app.ButtonGroup.Title = 'Button Group';
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Reator 1';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Reator 2';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup);
            app.RadioButton3.Text = 'Reator 3';
            app.RadioButton4 = uiradiobutton(app.ButtonGroup);
            app.RadioButton4.Text = 'Reator 4';
            app.RadioButton5 = uiradiobutton(app.ButtonGroup);
            app.RadioButton5.Text = 'Reator 5';
            app.RadioButton6 = uiradiobutton(app.ButtonGroup);
            app.RadioButton6.Text = 'Reator 6';
            app.RadioButton7 = uiradiobutton(app.ButtonGroup);
            app.RadioButton7.Text = 'Reator 7';
            app.RadioButton8 = uiradiobutton(app.ButtonGroup);
            app.RadioButton8.Text = 'Reator 8';
            app.RadioButton9 = uiradiobutton(app.ButtonGroup);
            app.RadioButton9.Text = 'Reator 9';
            app.RadioButton10 = uiradiobutton(app.ButtonGroup);
            app.RadioButton10.Text = 'Reator 10';
            app.RadioButton11 = uiradiobutton(app.ButtonGroup);
            app.RadioButton11.Text = 'Reator 11';
            app.RadioButton12 = uiradiobutton(app.ButtonGroup);
            app.RadioButton12.Text = 'Reator 12';
            app.Panel2 = uipanel(app.Tab3);
            app.Panel2.Title = 'Panel';
            app.Panel2.Units = 'pixels';
            app.LabelTextArea = uilabel(app.Panel2);
            app.LabelTextArea.Text = '';
            app.Status = uitextarea(app.Panel2);
            app.Status.Editable = 'off';
            app.Status.Value = {'Estatus..'};
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Plot';
            app.Tab5 = uitab(app.TabGroup2);
            app.Tab5.Units = 'pixels';
            app.Tab5.Title = 'Dados';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end