classdef gui3_backup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup2                matlab.ui.container.TabGroup
        Tab3                     matlab.ui.container.Tab
        Label12                  matlab.ui.control.Label
        Panel3                   matlab.ui.container.Panel
        LabelRockerSwitch        matlab.ui.control.Label
        RockerSwitch             matlab.ui.control.RockerSwitch
        LabelNinetyDegreeGauge   matlab.ui.control.Label
        NinetyDegreeGauge        matlab.ui.control.NinetyDegreeGauge
        LabelGauge               matlab.ui.control.Label
        GaugeUmidade             matlab.ui.control.Gauge
        LabelNinetyDegreeGauge2  matlab.ui.control.Label
        NinetyDegreeGauge2       matlab.ui.control.NinetyDegreeGauge
        Panel                    matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        Button2                  matlab.ui.control.Button
        mOnOff                   matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        LabelLamp1               matlab.ui.control.Label
        Lamp1                    matlab.ui.control.Lamp
        LabelLamp2               matlab.ui.control.Label
        Lamp2                    matlab.ui.control.Lamp
        LabelLamp3               matlab.ui.control.Label
        Lamp3                    matlab.ui.control.Lamp
        LabelLamp4               matlab.ui.control.Label
        Lamp4                    matlab.ui.control.Lamp
        LabelLamp5               matlab.ui.control.Label
        Lamp5                    matlab.ui.control.Lamp
        LabelLamp6               matlab.ui.control.Label
        Lamp6                    matlab.ui.control.Lamp
        LabelLamp7               matlab.ui.control.Label
        Lamp7                    matlab.ui.control.Lamp
        LabelLamp8               matlab.ui.control.Label
        Lamp8                    matlab.ui.control.Lamp
        LabelLamp9               matlab.ui.control.Label
        Lamp9                    matlab.ui.control.Lamp
        LabelLamp10              matlab.ui.control.Label
        Lamp10                   matlab.ui.control.Lamp
        LabelLamp11              matlab.ui.control.Label
        Lamp11                   matlab.ui.control.Lamp
        LabelLamp12              matlab.ui.control.Label
        Lamp12                   matlab.ui.control.Lamp
        Tab2                     matlab.ui.container.Tab
        ButtonGroup              matlab.ui.container.ButtonGroup
        Reator1M                 matlab.ui.control.RadioButton
        Reator2M                 matlab.ui.control.RadioButton
        Reator3M                 matlab.ui.control.RadioButton
        Reator4M                 matlab.ui.control.RadioButton
        Reator5M                 matlab.ui.control.RadioButton
        Reator6M                 matlab.ui.control.RadioButton
        Reator7M                 matlab.ui.control.RadioButton
        Reator8M                 matlab.ui.control.RadioButton
        Reator9M                 matlab.ui.control.RadioButton
        Reator10M                matlab.ui.control.RadioButton
        Reator11M                matlab.ui.control.RadioButton
        Reator12M                matlab.ui.control.RadioButton
        Panel2                   matlab.ui.container.Panel
        LabelTextArea            matlab.ui.control.Label
        Status                   matlab.ui.control.TextArea
        Tab4                     matlab.ui.container.Tab
        UIAxes2                  matlab.ui.control.UIAxes
        Bplot                    matlab.ui.control.Button
        Label13                  matlab.ui.control.Label
        NumericEditField2        matlab.ui.control.NumericEditField
        LabelNumericEditField    matlab.ui.control.Label
        NumericEditField1        matlab.ui.control.NumericEditField
        Tab5                     matlab.ui.container.Tab
        TextArea                 matlab.ui.control.TextArea
        Button3                  matlab.ui.control.Button
        Tab6                     matlab.ui.container.Tab
        Tab7                     matlab.ui.container.Tab
    end

    %     arduino = Bluetooth('HC-06', 1);
    %     T6615 = Bluetooth('SAAB', 1);
    properties (Access = public)
        autoUpdate
        r1 % Description
        r2 % Description
        r3
        r4
        r5
        r6
        r7
        r8
        r9
        r10
        r11
        r12
        radiovisible = 0 % Description
        xdata % Description
        ydata
        %         arduino = Bluetooth('HC-06', 1)
        %         T6615 = Bluetooth('SAAB', 1)
        %
    end
    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = public)
        
        function results = rotina(app)
            rotina_gui;
        end    
        
        function results = teste(app)
            pedro = 'oi'
        end
        
        function results = radioBox(app)
            if app.radiovisible == 1
                app.Reator1M.Enable = 'on';
                app.Reator2M.Enable = 'on';
                app.Reator3M.Enable = 'on';
                app.Reator4M.Enable = 'on';
                app.Reator5M.Enable = 'on';
                app.Reator6M.Enable = 'on';
                app.Reator7M.Enable = 'on';
                app.Reator8M.Enable = 'on';
                app.Reator9M.Enable = 'on';
                app.Reator10M.Enable = 'on';
                app.Reator11M.Enable = 'on';
                app.Reator12M.Enable = 'on';
            else
                app.Reator1M.Enable = 'off';
                app.Reator2M.Enable = 'off';
                app.Reator3M.Enable = 'off';
                app.Reator4M.Enable = 'off';
                app.Reator5M.Enable = 'off';
                app.Reator6M.Enable = 'off';
                app.Reator7M.Enable = 'off';
                app.Reator8M.Enable = 'off';
                app.Reator9M.Enable = 'off';
                app.Reator10M.Enable = 'off';
                app.Reator11M.Enable = 'off';
                app.Reator12M.Enable = 'off';
            end
            
            
        end
        
    end %fim dos metodos


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % cd 'C:\Users\Pedro\Google Drive\Pedro\ifpb\software' %mudar para padrão
            % clear all; % limpar todas as variaveis
            % addpath('C:\Users\Pedro\Google Drive\Pedro\ifpb\software'); %adicionar o path
            % javaaddpath 'C:\Users\pedro\Google Drive\Pedro\ifpb\software\sqljdbc_6.0\enu\jre7\sqljdbc41.jar'
            app.autoUpdate = 1;
        end

        % Button pushed function: Bplot
        function bPloclick(app, event)
            n1= app.NumericEditField1.Value;
            n2= app.NumericEditField2.Value;
            if n1>0 && n2>0 && n1<n2
                conn = database('Saab Matlab','root','');
                i=0;
                sqlquery = sprintf('SELECT id FROM `matlab`.`Reator 12` WHERE id >= %d AND id <=%d ;',n1,n2);
                x = exec(conn,sqlquery);
                x = fetch(x);
                x = cell2mat(x.Data);
                while i<12
                    
                    i=i+1; %contador
                    iS = int2str(i); %converte o contador em uma strin
                    sqlquery = sprintf('SELECT ppm FROM `matlab`.`Reator %s` WHERE id >= %d AND id <= %d;',iS,n1,n2);
                    y = exec(conn,sqlquery);
                    y = fetch(y);
                    y=y.Data;
                    v{i} = cell2mat(y);
                    %close(conn);
                end
                %scatter(x,v1)
                
                plot(app.UIAxes2,x,v{1},'-s',x,v{2},'-s',x,v{3},'-s',x,v{4},'-s',x,v{5},'-s',x,v{6},'-s',x,v{7},'-s',x,v{8},'-s',x,v{9},'-s',x,v{10},'-s',x,v{11},'-s',x,v{12},'-s')
            end
            %title('Concentração de co2')
            %xlabel('Amostra:')
            %ylabel('PPM')
            %legend('show')
            %legend('Reator 1','Reator 2','Reator 3','Reator 4','Reator 5','Reator 6','Reator 7','Reator 8','Reator 9','Reator 10','Reator 11','Reator 12')
            
            
        end

        % Value changed function: RockerSwitch
        function RockerSwitchValueChanged(app, event)
            value = app.RockerSwitch.Value;
            if strcmp(value,'On')
                %app.Button2.BackgroundColor = [0.128 0.255 0];
                rotina(app);
            else
                %app.Button2.BackgroundColor = [0.128 0.255 0];
                app.Lamp1.Enable = 'off';
                app.LabelLamp1.Enable = 'off';
                app.Lamp1.Color = [1 0 0];
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            %app.autoUpdate = 1;
            if(app.autoUpdate)
                if app.Reator1M.Value == true
                    app.Status.Value='Reator 1 Selecionado.';
                    app.r1=1;
                end
                if app.Reator2M.Value == true
                    app.Status.Value='Reator 2 Selecionado.';
                    app.r2=1;
                end
                if app.Reator3M.Value == true
                    app.Status.Value='Reator 3 Selecionado.';
                    app.r3=1;
                end
                if app.Reator4M.Value == true
                    app.Status.Value='Reator 4 Selecionado.';
                    app.r4=1;
                end
                if app.Reator5M.Value == true
                    app.Status.Value='Reator 5 Selecionado.';
                    app.r5=1;
                end
                if app.Reator6M.Value == true
                    app.Status.Value='Reator 6 Selecionado.';
                    app.r6=1;
                end
                if app.Reator7M.Value == true
                    app.Status.Value='Reator 7 Selecionado.';
                    app.r7=1;
                end
                if app.Reator8M.Value == true
                    app.Status.Value='Reator 8 Selecionado.';
                    app.r8=1;
                end
                if app.Reator9M.Value == true
                    app.Status.Value='Reator 9 Selecionado.';
                    app.r9=1;
                end
                if app.Reator10M.Value == true
                    app.Status.Value='Reator 10 Selecionado.';
                    app.r10=1;
                end
                if app.Reator11M.Value == true
                    app.Status.Value='Reator 11 Selecionado.';
                    app.r11=1;
                end
                if app.Reator12M.Value == true
                    app.Status.Value='Reator 12 Selecionado.';
                    app.r12=1;
                end
                
            end
            
        end

        % Button pushed function: Button2
        function Button2ButtonPushed(app, event)
            
            app.Status.Value='Modo Manual. Inicializando dispositivos...';
            app.radiovisible=1;
            radioBox(app);
            rotina_manual_gui;
            
        end

        % Selection change function: mOnOff
        function mOnOffSelectionChanged(app, event)
            selectedTab = app.mOnOff.SelectedTab;
            if strcmp(selectedTab.Title,'Manual')
                app.Status.Value='Modo Manual. Inicializando dispositivos...';
                app.radiovisible=1;
                radioBox(app);
                rotina_manual_gui;
                
            else
                app.Status.Value='Modo Automático.';
            end
        end

        % Button pushed function: Button3
        function Button3ButtonPushed(app, event)
            gui_dados;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Sistema de biodegração de Polímeros';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Title = 'Principal';
            app.Label12 = uilabel(app.Tab3);
            app.Label12.Text = 'Sistema de Biodegradação de Polímeros';
            app.Panel3 = uipanel(app.Tab3);
            app.Panel3.Title = 'Panel';
            app.LabelRockerSwitch = uilabel(app.Panel3);
            app.LabelRockerSwitch.Text = 'Rocker Switch';
            app.RockerSwitch = uiswitch(app.Panel3, 'rocker');
            app.RockerSwitch.ValueChangedFcn = createCallbackFcn(app, @RockerSwitchValueChanged, true);
            app.LabelNinetyDegreeGauge = uilabel(app.Panel3);
            app.LabelNinetyDegreeGauge.Text = 'Temperatura do Sistema';
            app.NinetyDegreeGauge = uigauge(app.Panel3, 'ninetydegree');
            app.NinetyDegreeGauge.Limits = [15 90];
            app.NinetyDegreeGauge.ScaleDirection = 'counterclockwise';
            app.NinetyDegreeGauge.Value = 15;
            app.LabelGauge = uilabel(app.Panel3);
            app.LabelGauge.Text = 'Umidade do Sistema';
            app.GaugeUmidade = uigauge(app.Panel3, 'circular');
            app.GaugeUmidade.Limits = [0 1000];
            app.GaugeUmidade.MajorTicks = [0 250 500 750 1000];
            app.LabelNinetyDegreeGauge2 = uilabel(app.Panel3);
            app.LabelNinetyDegreeGauge2.Text = 'Dias de incubação';
            app.NinetyDegreeGauge2 = uigauge(app.Panel3, 'ninetydegree');
            app.Panel = uipanel(app.Tab3);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed, true);
            app.mOnOff = uitabgroup(app.Tab3);
            app.Tab = uitab(app.mOnOff);
            app.Tab.Title = 'Automático';
            app.LabelLamp1 = uilabel(app.Tab);
            app.LabelLamp1.Enable = 'off';
            app.LabelLamp1.Text = 'Reator 1';
            app.Lamp1 = uilamp(app.Tab);
            app.Lamp1.Enable = 'off';
            app.LabelLamp2 = uilabel(app.Tab);
            app.LabelLamp2.Enable = 'off';
            app.LabelLamp2.Text = 'Reator  2';
            app.Lamp2 = uilamp(app.Tab);
            app.Lamp2.Enable = 'off';
            app.LabelLamp3 = uilabel(app.Tab);
            app.LabelLamp3.Enable = 'off';
            app.LabelLamp3.Text = 'Reator  3';
            app.Lamp3 = uilamp(app.Tab);
            app.Lamp3.Enable = 'off';
            app.LabelLamp4 = uilabel(app.Tab);
            app.LabelLamp4.Enable = 'off';
            app.LabelLamp4.Text = 'Reator  4';
            app.Lamp4 = uilamp(app.Tab);
            app.Lamp4.Enable = 'off';
            app.LabelLamp5 = uilabel(app.Tab);
            app.LabelLamp5.Enable = 'off';
            app.LabelLamp5.Text = 'Reator  5';
            app.Lamp5 = uilamp(app.Tab);
            app.Lamp5.Enable = 'off';
            app.LabelLamp6 = uilabel(app.Tab);
            app.LabelLamp6.Enable = 'off';
            app.LabelLamp6.Text = 'Reator  6';
            app.Lamp6 = uilamp(app.Tab);
            app.Lamp6.Enable = 'off';
            app.LabelLamp7 = uilabel(app.Tab);
            app.LabelLamp7.Enable = 'off';
            app.LabelLamp7.Text = 'Reator  7';
            app.Lamp7 = uilamp(app.Tab);
            app.Lamp7.Enable = 'off';
            app.LabelLamp8 = uilabel(app.Tab);
            app.LabelLamp8.Enable = 'off';
            app.LabelLamp8.Text = 'Reator  8';
            app.Lamp8 = uilamp(app.Tab);
            app.Lamp8.Enable = 'off';
            app.LabelLamp9 = uilabel(app.Tab);
            app.LabelLamp9.Enable = 'off';
            app.LabelLamp9.Text = 'Reator 9';
            app.Lamp9 = uilamp(app.Tab);
            app.Lamp9.Enable = 'off';
            app.LabelLamp10 = uilabel(app.Tab);
            app.LabelLamp10.Enable = 'off';
            app.LabelLamp10.Text = 'Reator  10';
            app.Lamp10 = uilamp(app.Tab);
            app.Lamp10.Enable = 'off';
            app.LabelLamp11 = uilabel(app.Tab);
            app.LabelLamp11.Enable = 'off';
            app.LabelLamp11.Text = 'Reator  11';
            app.Lamp11 = uilamp(app.Tab);
            app.Lamp11.Enable = 'off';
            app.LabelLamp12 = uilabel(app.Tab);
            app.LabelLamp12.Enable = 'off';
            app.LabelLamp12.Text = 'Reator  12';
            app.Lamp12 = uilamp(app.Tab);
            app.Lamp12.Enable = 'off';
            app.Tab2 = uitab(app.mOnOff);
            app.Tab2.Title = 'Manual';
            app.ButtonGroup = uibuttongroup(app.Tab2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Reator1M = uiradiobutton(app.ButtonGroup);
            app.Reator1M.Enable = 'off';
            app.Reator1M.Text = 'Reator 1';
            app.Reator1M.Value = true;
            app.Reator2M = uiradiobutton(app.ButtonGroup);
            app.Reator2M.Enable = 'off';
            app.Reator2M.Text = 'Reator 2';
            app.Reator3M = uiradiobutton(app.ButtonGroup);
            app.Reator3M.Enable = 'off';
            app.Reator3M.Text = 'Reator 3';
            app.Reator4M = uiradiobutton(app.ButtonGroup);
            app.Reator4M.Enable = 'off';
            app.Reator4M.Text = 'Reator 4';
            app.Reator5M = uiradiobutton(app.ButtonGroup);
            app.Reator5M.Enable = 'off';
            app.Reator5M.Text = 'Reator 5';
            app.Reator6M = uiradiobutton(app.ButtonGroup);
            app.Reator6M.Enable = 'off';
            app.Reator6M.Text = 'Reator 6';
            app.Reator7M = uiradiobutton(app.ButtonGroup);
            app.Reator7M.Enable = 'off';
            app.Reator7M.Text = 'Reator 7';
            app.Reator8M = uiradiobutton(app.ButtonGroup);
            app.Reator8M.Enable = 'off';
            app.Reator8M.Text = 'Reator 8';
            app.Reator9M = uiradiobutton(app.ButtonGroup);
            app.Reator9M.Enable = 'off';
            app.Reator9M.Text = 'Reator 9';
            app.Reator10M = uiradiobutton(app.ButtonGroup);
            app.Reator10M.Enable = 'off';
            app.Reator10M.Text = 'Reator 10';
            app.Reator11M = uiradiobutton(app.ButtonGroup);
            app.Reator11M.Enable = 'off';
            app.Reator11M.Text = 'Reator 11';
            app.Reator12M = uiradiobutton(app.ButtonGroup);
            app.Reator12M.Enable = 'off';
            app.Reator12M.Text = 'Reator 12';
            app.Panel2 = uipanel(app.Tab3);
            app.Panel2.Title = 'Panel';
            app.LabelTextArea = uilabel(app.Panel2);
            app.LabelTextArea.Text = '';
            app.Status = uitextarea(app.Panel2);
            app.Status.Editable = 'off';
            app.Status.Value = {'Estatus..'};
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Title = 'Gráfico';
            app.UIAxes2 = uiaxes(app.Tab4);
            title(app.UIAxes2, 'Concentração de co2')
            xlabel(app.UIAxes2, 'Amostras')
            ylabel(app.UIAxes2, 'PPM')
            app.Bplot = uibutton(app.Tab4, 'push');
            app.Bplot.ButtonPushedFcn = createCallbackFcn(app, @bPloclick, true);
            app.Bplot.Text = 'Plotar';
            app.Label13 = uilabel(app.Tab4);
            app.Label13.Text = 'Ponto Final';
            app.NumericEditField2 = uieditfield(app.Tab4, 'numeric');
            app.NumericEditField2.ValueDisplayFormat = '%.0f';
            app.LabelNumericEditField = uilabel(app.Tab4);
            app.LabelNumericEditField.Text = 'Ponto Inicial';
            app.NumericEditField1 = uieditfield(app.Tab4, 'numeric');
            app.NumericEditField1.ValueDisplayFormat = '%.0f';
            app.Tab5 = uitab(app.TabGroup2);
            app.Tab5.Title = 'Dados';
            app.TextArea = uitextarea(app.Tab5);
            app.Button3 = uibutton(app.Tab5, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed, true);
            app.Tab6 = uitab(app.TabGroup2);
            app.Tab6.Title = 'Análise de dados';
            app.Tab7 = uitab(app.TabGroup2);
            app.Tab7.Title = 'Configurações';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui3_backup

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end